classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        add_file         matlab.ui.container.Menu
        stopButton       matlab.ui.control.Button
        Label_3          matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        startButton      matlab.ui.control.Button
        kLabel           matlab.ui.control.Label
        kSlider          matlab.ui.control.Slider
        kLabel_2         matlab.ui.control.Label
        kSlider_2        matlab.ui.control.Slider
        kLabel_3         matlab.ui.control.Label
        kSlider_3        matlab.ui.control.Slider
        kLabel_4         matlab.ui.control.Label
        kSlider_4        matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        Slider_2Label    matlab.ui.control.Label
        Slider_2         matlab.ui.control.Slider
        Slider_3Label    matlab.ui.control.Label
        Slider_3         matlab.ui.control.Slider
        Slider_4Label    matlab.ui.control.Label
        Slider_4         matlab.ui.control.Slider
        Slider_5Label    matlab.ui.control.Label
        Slider_5         matlab.ui.control.Slider
        kSlider_5Label   matlab.ui.control.Label
        kSlider_5        matlab.ui.control.Slider
        Knob_2Label      matlab.ui.control.Label
        Knob_3           matlab.ui.control.Knob
        Knob_3Label      matlab.ui.control.Label
        Knob_2           matlab.ui.control.Knob
        SwitchLabel      matlab.ui.control.Label
        Switch           matlab.ui.control.Switch
        DropDown_2Label  matlab.ui.control.Label
        DropDown_2       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        filename; % Description
        y;
        L;
        Fs;
        y_de;
        resume;
        state = 0; % 0:停止，1：播放，2：暂停
        volume;
          % 查看audioread文档找到支持哪一些文件
        extendsions = {'.ogg';'.mp3';'.wav';'.flac';'.au';'.aif';'.aiff';'.mp4';'.m4a'}
        total_frame % 音频长度，用于进度条的更新
        fs % 音频采样率
        player % 音频播放器
    end


    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)

            if app.state == 0
                app.state = 1;
                app.resume = 1;
                app.L = length(app.y);
                step = app.Fs;
                for head = app.resume:step:app.L
                    t = clock; %计算时间
                    if app.state == 0
                        break;
                    elseif app.state == 2
                        app.resume = head;
                        break;
                    end
                    bottom = min(head + step - 1, app.L);
                    

                    s = app.y(head:bottom);
     
 
                    freq_points_gain = [app.Slider.Value, app.Slider_2.Value, app.Slider_3.Value, app.Slider_4.Value, app.Slider_5.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
if app.Slider.Value==0&&app.Slider_2.Value==0&&app.Slider_3.Value==0&&app.Slider_4.Value==0&&app.Slider_5.Value==0&&app.kSlider.Value==0&&app.kSlider_2.Value==0&&app.kSlider_3.Value==0&&app.kSlider_4.Value==0&&app.kSlider_5.Value==0

else
                       s = time_domain(s, app.Fs, freq_points_gain);
end
                    if head==1
   if strcmp(app.Switch.Value,'On')
               r = bottom;
t1 =5;
n1 = round(t1*app.Fs);
n2 = round((round(r/app.Fs)-t1)*app.Fs);
for i = 1:n1
    s(i,1) = (i^2/n1^2)*s(i,1);
    s(i,2) = (i^2/n1^2)*s(i,2);
end
for k = n2:r
    s(k,1) = s(k,1) - s(k,1).*(k-n2)^2/(r-n2)^2;
    s(k,2) = s(k,2) - s(k,2).*(k-n2)^2/(r-n2)^2;
end

           else
                                      end
                    end
 
                    if app.Knob_3.Value~=1
                        s = change_tone(s,app.Knob_3.Value);
                    end
                    if app.Knob_2.Value~=1
                        s = wsola(s, 1/app.Knob_2.Value);
                    end
                    sound(s,app.Fs);
                    
                    time = length(s)/app.Fs - (clock-t);
                    if app.Knob_2.Value>1
                        pause((time-0.096)/app.Knob_2.Value);
                    else
                     pause(time-0.096);
                    end
                      value = app.DropDown.Value;
            switch value
                case '无'
                   
                case '1'
                    s1 = reverberation(s,app.Fs,1);
                    audiowrite('一级混响',s1,app.Fs);;
                case '2'
                    s2 = reverberation(s,app.Fs,2);
                    audiowrite('二级混响',s2,app.Fs);;
                case '3'
                    s3 = reverberation(s,app.Fs,3);
                     audiowrite('三级混响',s3,app.Fs);;
                    
            end
                
            end
                if bottom == app.L
                    app.state = 0;
              
            elseif app.state == 1
                app.state = 2;
            elseif app.state == 2
                app.state = 1;
        end
                step = app.Fs;
                for head = app.resume:step:app.L
                    t = clock; %计算时间
                    if app.state == 0
                        break;
                    elseif app.state == 2
                        app.resume = head;
                        break;
                    end
                    bottom = min(head + step - 1, app.L);
                    
                    
                freq_points_gain = [app.Slider.Value, app.Slider_2.Value, app.Slider_3.Value, app.Slider_4.Value, app.Slider_5.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
           
if app.Slider.Value==0&&app.Slider_2.Value==0&&app.Slider_3.Value==0&&app.Slider_4.Value==0&&app.Slider_5.Value==0&&app.kSlider.Value==0&&app.kSlider_2.Value==0&&app.kSlider_3.Value==0&&app.kSlider_4.Value==0&&app.kSlider_5.Value==0

else
                       s = time_domain(s, app.Fs, freq_points_gain);
end                    

                              if strcmp(app.Switch.Value,'On')
               r = app.total_frame;
t1 =7;
n1 = round(t1*app.Fs);
n2 = round((round(r/app.fs)-t1)*app.Fs);
for i = 1:n1
    r(i,1) = (i^2/n1^2)*r(i,1);
    r(i,2) = (i^2/n1^2)*r(i,2);
end

for k = n2:r
    r(k,1) = app.r(k,1) - app.audio_data(k,1)*(k-n2)^2/(r-n2)^2;
    r(k,2) = app.r(k,2) - app.audio_data(k,2)*(k-n2)^2/(r-n2)^2;
end

           else
           end
                    if app.Knob_3.Value~=1
                        s = change_tone(s,app.Knob_3.Value);
                    end
                    if app.Knob_2.Value~=1
                        s = wsola(s, 1/app.Knob_2.Value);
                    end
                                switch value
                case '无'
                   
                case '1'
                    s1 = reverberation(s,app.Fs,1);
                    audiowrite('一级混响',s1,app.Fs);;
                case '2'
                    s2 = reverberation(s,app.Fs,2);
                    audiowrite('二级混响',s2,app.Fs);;
                case '3'
                    s3 = reverberation(s,app.Fs,3);
                     audiowrite('三级混响',s3,app.Fs);;
                    
            end
                
                    
                    sound(s,app.fs);
                    
                    time = length(s)/app.Fs - (clock-t);
                    if app.Knob_2.Value>1
                        pause((time-0.096)/app.Knob_2.Value);
                    else
                     pause(time-0.096);
                    end
                    
                end
                if bottom == app.L
                    app.state = 0;
                end
        end
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.filename = value;
            app.state = 0;
        end

        % Callback function
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.volume = changingValue;
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.state = 0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            value = app.kSlider.Value;
            
        end

        % Value changed function: kSlider_2
        function kSlider_2ValueChanged(app, event)
            value = app.kSlider_2.Value;
            
        end

        % Value changed function: kSlider_3
        function kSlider_3ValueChanged(app, event)
            value = app.kSlider_3.Value;
            
        end

        % Value changed function: kSlider_4
        function kSlider_4ValueChanged(app, event)
            value = app.kSlider_4.Value;
            
        end

        % Value changed function: kSlider_5
        function kSlider_5ValueChanged(app, event)
            value = app.kSlider_5.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '无'
                    app.Slider.Value = 0;
                    app.Slider_2.Value = 0;
                    app.Slider_3.Value = 0;
                    app.Slider_4.Value = 0;
                    app.Slider_5.Value = 0;
                    app.kSlider.Value = 0;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = 0;
                    app.kSlider_5.Value = 0;
                case '古典'
                    app.Slider.Value = 4;
                    app.Slider_2.Value = 4;
                    app.Slider_3.Value = 3;
                    app.Slider_4.Value = 2;
                    app.Slider_5.Value = -1;
                    app.kSlider.Value = -1;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 1;
                    app.kSlider_4.Value = 3;
                    app.kSlider_5.Value = 4;
                case '流行'
                    app.Slider.Value = -1;
                    app.Slider_2.Value = -1;
                    app.Slider_3.Value = 0;
                    app.Slider_4.Value = 1;
                    app.Slider_5.Value = 4;
                    app.kSlider.Value = 3;
                    app.kSlider_2.Value = 1;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = -1;
                    app.kSlider_5.Value = 1;
                case '古风'
                    app.Slider.Value = 3;
                    app.Slider_2.Value = 2;
                    app.Slider_3.Value = 2;
                    app.Slider_4.Value = 0;
                    app.Slider_5.Value = -1;
                    app.kSlider.Value = 3;
                    app.kSlider_2.Value = 4;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = 1;
                    app.kSlider_5.Value = 3;
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(class(value));
        end

        % Callback function
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
        end

        % Menu selected function: add_file
        function add_fileMenuSelected(app, event)
             s = '';
            for index = 1:length(app.extendsions)
                s = strcat(s, '*', app.extendsions{index}, ';');
            end
            [files,path] = uigetfile({s, "音频文件"}, 'MultiSelect', 'on');
            % 如果没有选择文件，则返回
            if ~ischar(path)
                return
            end
            % 如果是一个文件
            if ~iscell(files)
                 [app.y, app.Fs] = audioread(fullfile(path, files));
                return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '添加';
            app.add_file = uimenu(app.Menu);
            app.add_file.MenuSelectedFcn = createCallbackFcn(app, @add_fileMenuSelected, true);
            app.add_file.Text = '文件';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '音乐风格';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'无', '古典', '流行', '爵士'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '无';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.kLabel = uilabel(app.UIFigure);
            app.kLabel.Text = '1k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.Limits = [-12 12];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kLabel_2 = uilabel(app.UIFigure);
            app.kLabel_2.Text = '2k';
            app.kSlider_2 = uislider(app.UIFigure);
            app.kSlider_2.Limits = [-12 12];
            app.kSlider_2.ValueChangedFcn = createCallbackFcn(app, @kSlider_2ValueChanged, true);
            app.kLabel_3 = uilabel(app.UIFigure);
            app.kLabel_3.Text = '4k';
            app.kSlider_3 = uislider(app.UIFigure);
            app.kSlider_3.Limits = [-12 12];
            app.kSlider_3.ValueChangedFcn = createCallbackFcn(app, @kSlider_3ValueChanged, true);
            app.kLabel_4 = uilabel(app.UIFigure);
            app.kLabel_4.Text = '8k';
            app.kSlider_4 = uislider(app.UIFigure);
            app.kSlider_4.Limits = [-12 12];
            app.kSlider_4.ValueChangedFcn = createCallbackFcn(app, @kSlider_4ValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '31';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-12 12];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '62';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-12 12];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = '125';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-12 12];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '250';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-12 12];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = '500';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-12 12];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.kSlider_5Label = uilabel(app.UIFigure);
            app.kSlider_5Label.Text = '16k';
            app.kSlider_5 = uislider(app.UIFigure);
            app.kSlider_5.Limits = [-12 12];
            app.kSlider_5.ValueChangedFcn = createCallbackFcn(app, @kSlider_5ValueChanged, true);
            app.Knob_2Label = uilabel(app.UIFigure);
            app.Knob_2Label.Text = '音调';
            app.Knob_3 = uiknob(app.UIFigure, 'continuous');
            app.Knob_3.Limits = [0.5 2];
            app.Knob_3.MajorTicks = [0.5 0.75 1 1.25 1.5 2];
            app.Knob_3.MajorTickLabels = {'很高', '较高', '正常', '较低', '很低', '非常低'};
            app.Knob_3.MinorTicks = [0.5 0.75 1 1.25 1.5 2];
            app.Knob_3.Value = 1;
            app.Knob_3Label = uilabel(app.UIFigure);
            app.Knob_3Label.Text = '播放速度';
            app.Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Knob_2.Limits = [0.5 2];
            app.Knob_2.MajorTicks = [0.5 0.75 1 1.25 1.5 1.75 2];
            app.Knob_2.MajorTickLabels = {'0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.Knob_2.MinorTicks = [0.5 0.75 1 1.25 1.5 1.75 2];
            app.Knob_2.Value = 1;
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = '淡入淡出';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '混响';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'无', '1', '2', '3'};
            app.DropDown_2.Value = '无';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end